classdef ViewData_2023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FlipPosNegSwitch          matlab.ui.control.Switch
        FlipPosNegSwitchLabel     matlab.ui.control.Label
        min_prominence            matlab.ui.control.NumericEditField
        MinPeakProminence01Label  matlab.ui.control.Label
        ResetAXISButton           matlab.ui.control.Button
        bin_width                 matlab.ui.control.NumericEditField
        binwidthmsLabel_2         matlab.ui.control.Label
        high_Threshold            matlab.ui.control.NumericEditField
        HigherThresholdmVLabel    matlab.ui.control.Label
        low_Threshold             matlab.ui.control.NumericEditField
        LowerThresholduVLabel     matlab.ui.control.Label
        ShowHistogramButton       matlab.ui.control.Button
        OpendataButton            matlab.ui.control.Button
        UIAxes_psth               matlab.ui.control.UIAxes
        UIAxes_raw                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data %RAW DATA
        data_dir = '';
        t_ %Time
        y_keep %highpassed data (keep)
        y_ %highpassed data
        ind_ %detected spikes indecies
        
    end
    
    methods (Access = private)
        
        function detect_Spks(app)
            %peak find
            y = app.y_;
            t = app.t_;
            th1 = app.low_Threshold.Value;
            minprominence = app.min_prominence.Value;
            if app.high_Threshold.Value ~= 0
                th2 = app.high_Threshold.Value;
                [~,ind_min] = findpeaks(y,'MinPeakHeight',th1, 'MinPeakProminence', th1*minprominence);%, 'MinPeakDistance', 10);%, 'MaxPeakWidth', 30);%, 'MinPeakWidth', 10) ;
                [~,ind_max] = findpeaks(y,'MinPeakHeight',th2, 'MinPeakProminence', th2*0.8);%, 'MinPeakDistance', 10);%, 'MaxPeakWidth', 30);%, 'MinPeakWidth', 10) ;
                ind1 = setdiff(ind_min, ind_max);
            else
                
                %[~,ind_min] = findpeaks(y,'MinPeakHeight',th1, 'MinPeakProminence', th1*0.8);% 'MinPeakDistance', 10, 'MaxPeakWidth', 30);%, 'MinPeakWidth', 10) ;
                %ind1 =  ind_min;
                [~,ind1] = findpeaks(y,'MinPeakHeight',th1, 'MinPeakProminence', th1*minprominence);% 'MinPeakDistance', 10, 'MaxPeakWidth', 30);%, 'MinPeakWidth', 10) ;
                
            end
            
            %slope check ( (threshold * 0.8)mV / 0.5ms)
            sampt = t(2) - t(1);
            ind2 = ind1 - round(0.5/sampt/1000); % 0.5ms before peak
            
            ind1 = ind1(ind2 > 0);
            ind2 = ind2(ind2 > 0);
            
            slope = y(ind1) - y(ind2);
            app.ind_ = ind1(slope > th1*0.8);
        end
        
        function plot_raw(app)
            t = app.t_;
            y = app.y_;
            ind = app.ind_;
            th1 = app.low_Threshold.Value;
            
            plot(app.UIAxes_raw, t, y, 'b-')
            
            if ~isempty(ind)
                hold(app.UIAxes_raw, 'on')
                t_th = ones(length(ind),1)*th1;
                plot(app.UIAxes_raw, t(ind), t_th, 'm*')
                hold(app.UIAxes_raw, 'off')
            end
        end
    
        function plot_hist(app)
            t = app.t_;
            ind = app.ind_;
            
            nbin = round((t(end) - t(1))/app.bin_width.Value*1000);
            histogram(app.UIAxes_psth, t(ind), nbin);
            app.UIAxes_psth.XLim = [t(1), t(end)];
            
            % fitting する？
            showfit = 0;
            if showfit == 1
                h = histogram(app.UIAxes_psth, t(ind), nbin);
                t_hist = h.BinEdges(1:length(h.BinCounts))';
                y_hist = h.BinCounts';
                % use 30 sec
                t_hist = t_hist(t_hist <= 20);
                y_hist = y_hist(t_hist <= 20);
                t0 = t_hist(1);
                fo = fitoptions('Method','NonlinearLeastSquares',...
                    'Lower',[0, 0.01, 0],...
                    'Upper',[max(y_hist), 30, max(y_hist)],...
                    'StartPoint',[10, 10, 0]);
                
                ft = fittype('a*exp(-x/b)+c', 'options', fo);
                y_fit = fit(t_hist - t0, y_hist, ft);
                %disp(y_fit);
                t_fit = t0: 0.1: 30+t0;
                hold(app.UIAxes_psth, 'on')
                plot(app.UIAxes_psth, t_fit, y_fit(t_fit-t0), 'r-')
                
                hold(app.UIAxes_psth, 'off')
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpendataButton
        function OpendataButtonPushed(app, event)
            [f, app.data_dir] = uigetfile([app.data_dir, '*.mat']);
            load([app.data_dir,f], '-mat', 'capturedData');
            app.data = capturedData;
            t = capturedData(:,1);
            y = capturedData(:,2);
            
            %Highpass filter
            [b,a] = butter(4, [0.02 0.5]);
            y = filtfilt(b,a,y);
            app.y_keep = y;
            app.y_ = y;
            app.t_ = t;

            flip = app.FlipPosNegSwitch.Value;

            if strcmp(flip, "On")
                app.y_ = -1 * app.y_keep;
            elseif strcmp(flip, "Off")
                app.y_ = app.y_keep;
            end
            
            
            %peak find
            detect_Spks(app)
            
            %Plot
            plot_raw(app)
            
            %Histogram
            if ~isempty(app.ind_)
                plot_hist(app)
            end
            
        end

        % Value changed function: low_Threshold
        function low_ThresholdValueChanged(app, event)
            if ~isempty(app.t_)
                detect_Spks(app)
                plot_raw(app)
            end
            
            if ~isempty(app.ind_)
                plot_hist(app)
            end
            
        end

        % Value changed function: high_Threshold
        function high_ThresholdValueChanged(app, event)
            if ~isempty(app.t_)
                detect_Spks(app)
                plot_raw(app)
            end
            
            if ~isempty(app.ind_)
                plot_hist(app)
            end
            
        end

        % Value changed function: bin_width
        function bin_widthValueChanged(app, event)
            if ~isempty(app.t_) && ~isempty(app.ind_)
                plot_hist(app)
            end
        end

        % Button pushed function: ShowHistogramButton
        function ShowHistogramButtonPushed(app, event)
            if ~isempty(app.t_) && ~isempty(app.ind_)
                plot_hist(app)
            end
        end

        % Button pushed function: ResetAXISButton
        function ResetAXISButtonPushed(app, event)
            if ~isempty(app.t_)
                app.UIAxes_raw.XLim = [app.t_(1), app.t_(end)];
                app.UIAxes_raw.YLimMode = 'auto';
            end
            
            if ~isempty(app.t_)
                app.UIAxes_psth.XLim = [app.t_(1), app.t_(end)];
                app.UIAxes_raw.YLimMode = 'auto';
            end
        end

        % Value changed function: min_prominence
        function min_prominenceValueChanged(app, event)
            %value = app.min_prominence.Value;
            if ~isempty(app.t_)
                detect_Spks(app)
                plot_raw(app)
            end
            
            if ~isempty(app.ind_)
                plot_hist(app)
            end
        end

        % Value changed function: FlipPosNegSwitch
        function FlipPosNegSwitchValueChanged(app, event)
            value = app.FlipPosNegSwitch.Value;

            if strcmp(value, "On")
                app.y_ = -1 * app.y_keep;
            elseif strcmp(value, "Off")
                app.y_ = app.y_keep;
            end
            
            %peak find
            detect_Spks(app)
            
            %Plot
            plot_raw(app)
            
            %Histogram
            if ~isempty(app.ind_)
                plot_hist(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_raw = uiaxes(app.UIFigure);
            title(app.UIAxes_raw, 'Raw Data')
            ylabel(app.UIAxes_raw, 'Voltage (uV)')
            app.UIAxes_raw.XLim = [-Inf Inf];
            app.UIAxes_psth = uiaxes(app.UIFigure);
            title(app.UIAxes_psth, 'PSTH')
            xlabel(app.UIAxes_psth, 'Time')
            ylabel(app.UIAxes_psth, 'Spike counts/bin')
            app.UIAxes_psth.XLim = [-Inf Inf];
            app.UIAxes_psth.YLim = [0 Inf];
            app.OpendataButton = uibutton(app.UIFigure, 'push');
            app.OpendataButton.ButtonPushedFcn = createCallbackFcn(app, @OpendataButtonPushed, true);
            app.OpendataButton.Text = 'Open data';
            app.ShowHistogramButton = uibutton(app.UIFigure, 'push');
            app.ShowHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramButtonPushed, true);
            app.ShowHistogramButton.Text = 'Show Histogram';
            app.LowerThresholduVLabel = uilabel(app.UIFigure);
            app.LowerThresholduVLabel.Text = 'Lower Threshold (uV):';
            app.low_Threshold = uieditfield(app.UIFigure, 'numeric');
            app.low_Threshold.ValueChangedFcn = createCallbackFcn(app, @low_ThresholdValueChanged, true);
            app.low_Threshold.Value = 50;
            app.HigherThresholdmVLabel = uilabel(app.UIFigure);
            app.HigherThresholdmVLabel.Text = 'Higher Threshold (uV):';
            app.high_Threshold = uieditfield(app.UIFigure, 'numeric');
            app.high_Threshold.ValueChangedFcn = createCallbackFcn(app, @high_ThresholdValueChanged, true);
            app.binwidthmsLabel_2 = uilabel(app.UIFigure);
            app.binwidthmsLabel_2.Text = 'bin width (ms):';
            app.bin_width = uieditfield(app.UIFigure, 'numeric');
            app.bin_width.ValueChangedFcn = createCallbackFcn(app, @bin_widthValueChanged, true);
            app.bin_width.Value = 100;
            app.ResetAXISButton = uibutton(app.UIFigure, 'push');
            app.ResetAXISButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAXISButtonPushed, true);
            app.ResetAXISButton.Text = 'Reset AXIS';
            app.MinPeakProminence01Label = uilabel(app.UIFigure);
            app.MinPeakProminence01Label.Text = 'MinPeakProminence(0-1):';
            app.min_prominence = uieditfield(app.UIFigure, 'numeric');
            app.min_prominence.ValueChangedFcn = createCallbackFcn(app, @min_prominenceValueChanged, true);
            app.FlipPosNegSwitchLabel = uilabel(app.UIFigure);
            app.FlipPosNegSwitchLabel.Text = 'Flip Pos/Neg';
            app.FlipPosNegSwitch = uiswitch(app.UIFigure, 'slider');
            app.FlipPosNegSwitch.ValueChangedFcn = createCallbackFcn(app, @FlipPosNegSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewData_2023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end